classdef SVV < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                      matlab.ui.Figure
      OpenButton                    matlab.ui.control.Button
      CloseButton                   matlab.ui.control.Button
      StepsizeEditField             matlab.ui.control.NumericEditField
      StepsizeEditFieldLabel        matlab.ui.control.Label
      CodeEditField                 matlab.ui.control.EditField
      CodeEditFieldLabel            matlab.ui.control.Label
      DataFilePathTextField         matlab.ui.control.EditField
      DebugModeLabel                matlab.ui.control.Label
      ImageEditField                matlab.ui.control.EditField
      ImageButton                   matlab.ui.control.Button
      MonitorEditField              matlab.ui.control.NumericEditField
      MonitorEditFieldLabel         matlab.ui.control.Label
      StopButton                    matlab.ui.control.Button
      StartButton                   matlab.ui.control.Button
      PauseButton                   matlab.ui.control.Button
      TrialnumberEditField          matlab.ui.control.NumericEditField
      TrialnumberEditFieldLabel     matlab.ui.control.Label
      FolderEditField               matlab.ui.control.EditField
      SubjectIDEditField            matlab.ui.control.EditField
      SubjectIDEditFieldLabel       matlab.ui.control.Label
      FolderButton                  matlab.ui.control.Button
      DistancecmEditField           matlab.ui.control.NumericEditField
      DistancecmEditFieldLabel      matlab.ui.control.Label
      UITable                       matlab.ui.control.Table
      PausedurationsEditField       matlab.ui.control.NumericEditField
      PausedurationsEditFieldLabel  matlab.ui.control.Label
      RepetitionsEditField          matlab.ui.control.NumericEditField
      RepetitionsEditFieldLabel     matlab.ui.control.Label
      FrequencyHzEditField          matlab.ui.control.NumericEditField
      FrequencyHzEditFieldLabel     matlab.ui.control.Label
      NUpEditField                  matlab.ui.control.NumericEditField
      NUpEditFieldLabel             matlab.ui.control.Label
      MDownEditField                matlab.ui.control.NumericEditField
      MDownEditFieldLabel           matlab.ui.control.Label
      WhitenoiseCheckBox            matlab.ui.control.CheckBox
      StartvalueEditField           matlab.ui.control.NumericEditField
      StartvalueEditFieldLabel      matlab.ui.control.Label
      DirectionDropDown             matlab.ui.control.DropDown
   end

   
   properties (Access = private)
      rootDataFolder          % Folder where data are stored
      imagePath = ''          % Image to display

      imageTexture            % Psychtoolbox texture
      window = []             % Psychtoolbox window handle

      appState = 'idle'       % idle, active, paused
      currentTrialNumber      % as it says
      data = []               % ultimately a structure containing the data
      dataFilePath = '';      % path to the data

      debugMode = false;      % For testing purposes
   end
   
   methods (Access = private)
      
      function InitializePsychtoolbox(app)
         if app.debugMode || ~isempty(app.window), return; end

         PsychDefaultSetup(2); 
         Screen('Preference', 'SkipSyncTests', 0);
         screenNum = app.MonitorEditField.Value; % set screen 
         Screen('Preference', 'VisualDebugLevel', 3);
         app.window = Screen('OpenWindow', screenNum);
         
         Screen('BlendFunction', app.window, 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA');
         img = imread(app.imagePath);
         app.imageTexture = Screen('MakeTexture', app.window, img);
         
         Screen('DrawTexture', app.window, app.imageTexture);
         Screen('Flip', app.window);
      end
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         app.DebugModeLabel.Visible = app.debugMode;

         % Restore data folder
         app.FolderEditField.Value = getpref('SVV', 'RootDataFolder', pwd);

         % Restore image path
         app.imagePath = getpref('SVV', 'ImagePath', '');
         if ~isempty(app.imagePath)
            [~, fn, ext] = fileparts(app.imagePath);
            app.ImageEditField.Value = [fn ext];
         end

         app.MonitorEditField.Value = getpref('SVV', 'MonitorNumber', 2);
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         delete(app)
         
      end

      % Button pushed function: FolderButton
      function FolderButtonPushed(app, event)
          selection = uigetdir(app.FolderEditField.Value);
          if ~isempty(selection)
             app.FolderEditField.Value = selection;
             setpref('SVV', 'RootDataFolder', selection)
          end
      end

      % Value changed function: FolderEditField
      function FolderEditFieldValueChanged(app, event)
         setpref('SVV', 'RootDataFolder', app.FolderEditField.Value);
      end

      % Button pushed function: ImageButton
      function ImageButtonPushed(app, event)
          [selection, folder] = uigetfile({'*.png;Image files}'}, 'Select an image', app.imagePath);
          if ~isempty(selection) && ~isequal(selection, 0)
             app.imagePath = fullfile(folder, selection);
             app.ImageEditField.Value = selection;
             setpref('SVV', 'ImagePath', app.imagePath);
          end         
      end

      % Value changed function: ImageEditField
      function ImageEditFieldValueChanged(app, event)
         value = app.ImageEditField.Value;
         selection = fullfile(fileparts(app.imagePath), value);
         if exist(selection, 'file')
             app.ImagePath = selection;
             setpref('SVV', 'ImagePath', selection)
         else
            [~, fn, ext] = fileparts(app.imagePath);
            app.ImageEditField.Value = [fn ext];
         end
         
      end

      % Button pushed function: StartButton
      function StartButtonPushed(app, event)
         
      end

      % Button pushed function: OpenButton
      function OpenButtonPushed(app, event)
         app.InitializePsychtoolbox();
         app.OpenButton.Visible = false;
      end

      % Button pushed function: CloseButton
      function CloseButtonPushed(app, event)
         Screen('CloseAll');
         app.window = [];
         app.OpenButton.Visible = true;
      end

      % Value changed function: MonitorEditField
      function MonitorEditFieldValueChanged(app, event)
         setpref('SVV', 'MonitorNumber', app.MonitorEditField.Value);
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'SVV';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.DirectionDropDown = uidropdown(app.UIFigure);
         app.DirectionDropDown.Items = {'Up / Down', 'Left / Right', 'Roll', 'Zoom'};
         app.DirectionDropDown.Value = 'Up / Down';
         app.StartvalueEditFieldLabel = uilabel(app.UIFigure);
         app.StartvalueEditFieldLabel.Text = 'Start value';
         app.StartvalueEditField = uieditfield(app.UIFigure, 'numeric');
         app.StartvalueEditField.Value = 2;
         app.WhitenoiseCheckBox = uicheckbox(app.UIFigure);
         app.WhitenoiseCheckBox.Text = 'White noise';
         app.WhitenoiseCheckBox.Value = true;
         app.MDownEditFieldLabel = uilabel(app.UIFigure);
         app.MDownEditFieldLabel.Text = 'M-Down';
         app.MDownEditField = uieditfield(app.UIFigure, 'numeric');
         app.MDownEditField.Limits = [1 Inf];
         app.MDownEditField.RoundFractionalValues = 'on';
         app.MDownEditField.ValueDisplayFormat = '%.0f';
         app.MDownEditField.Value = 4;
         app.NUpEditFieldLabel = uilabel(app.UIFigure);
         app.NUpEditFieldLabel.Text = 'N-Up';
         app.NUpEditField = uieditfield(app.UIFigure, 'numeric');
         app.NUpEditField.Limits = [1 Inf];
         app.NUpEditField.RoundFractionalValues = 'on';
         app.NUpEditField.ValueDisplayFormat = '%.0f';
         app.NUpEditField.Value = 1;
         app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
         app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
         app.FrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
         app.FrequencyHzEditField.Limits = [0.05 5];
         app.FrequencyHzEditField.Value = 1;
         app.RepetitionsEditFieldLabel = uilabel(app.UIFigure);
         app.RepetitionsEditFieldLabel.Text = 'Repetitions';
         app.RepetitionsEditField = uieditfield(app.UIFigure, 'numeric');
         app.RepetitionsEditField.Limits = [1 Inf];
         app.RepetitionsEditField.RoundFractionalValues = 'on';
         app.RepetitionsEditField.ValueDisplayFormat = '%.0f';
         app.RepetitionsEditField.Value = 100;
         app.PausedurationsEditFieldLabel = uilabel(app.UIFigure);
         app.PausedurationsEditFieldLabel.Text = 'Pause duration (s)';
         app.PausedurationsEditField = uieditfield(app.UIFigure, 'numeric');
         app.PausedurationsEditField.Limits = [0 Inf];
         app.PausedurationsEditField.Value = 2;
         app.UITable = uitable(app.UIFigure);
         app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
         app.UITable.RowName = {};
         app.DistancecmEditFieldLabel = uilabel(app.UIFigure);
         app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
         app.DistancecmEditField = uieditfield(app.UIFigure, 'numeric');
         app.DistancecmEditField.Limits = [0 Inf];
         app.DistancecmEditField.Value = 100;
         app.FolderButton = uibutton(app.UIFigure, 'push');
         app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
         app.FolderButton.Text = 'Folder';
         app.SubjectIDEditFieldLabel = uilabel(app.UIFigure);
         app.SubjectIDEditFieldLabel.Text = 'Subject ID';
         app.SubjectIDEditField = uieditfield(app.UIFigure, 'text');
         app.SubjectIDEditField.Value = 'dummy';
         app.FolderEditField = uieditfield(app.UIFigure, 'text');
         app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
         app.FolderEditField.Value = 'dummy';
         app.TrialnumberEditFieldLabel = uilabel(app.UIFigure);
         app.TrialnumberEditFieldLabel.Text = 'Trial number';
         app.TrialnumberEditField = uieditfield(app.UIFigure, 'numeric');
         app.TrialnumberEditField.Editable = 'off';
         app.PauseButton = uibutton(app.UIFigure, 'push');
         app.PauseButton.Enable = 'off';
         app.PauseButton.Text = 'Pause';
         app.StartButton = uibutton(app.UIFigure, 'push');
         app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
         app.StartButton.Text = 'Start';
         app.StopButton = uibutton(app.UIFigure, 'push');
         app.StopButton.Enable = 'off';
         app.StopButton.Text = 'Stop';
         app.MonitorEditFieldLabel = uilabel(app.UIFigure);
         app.MonitorEditFieldLabel.Text = 'Monitor';
         app.MonitorEditField = uieditfield(app.UIFigure, 'numeric');
         app.MonitorEditField.Limits = [1 Inf];
         app.MonitorEditField.RoundFractionalValues = 'on';
         app.MonitorEditField.ValueDisplayFormat = '%.0f';
         app.MonitorEditField.ValueChangedFcn = createCallbackFcn(app, @MonitorEditFieldValueChanged, true);
         app.MonitorEditField.Value = 2;
         app.ImageButton = uibutton(app.UIFigure, 'push');
         app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
         app.ImageButton.Text = 'Image';
         app.ImageEditField = uieditfield(app.UIFigure, 'text');
         app.ImageEditField.ValueChangedFcn = createCallbackFcn(app, @ImageEditFieldValueChanged, true);
         app.DebugModeLabel = uilabel(app.UIFigure);
         app.DebugModeLabel.Visible = 'off';
         app.DebugModeLabel.Text = 'Debug mode';
         app.DataFilePathTextField = uieditfield(app.UIFigure, 'text');
         app.DataFilePathTextField.Editable = 'off';
         app.CodeEditFieldLabel = uilabel(app.UIFigure);
         app.CodeEditFieldLabel.Text = 'Code';
         app.CodeEditField = uieditfield(app.UIFigure, 'text');
         app.StepsizeEditFieldLabel = uilabel(app.UIFigure);
         app.StepsizeEditFieldLabel.Text = 'Step size';
         app.StepsizeEditField = uieditfield(app.UIFigure, 'numeric');
         app.StepsizeEditField.Value = 2;
         app.CloseButton = uibutton(app.UIFigure, 'push');
         app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
         app.CloseButton.Text = 'Close';
         app.OpenButton = uibutton(app.UIFigure, 'push');
         app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
         app.OpenButton.Text = 'Open';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = SVV

         runningApp = getRunningApp(app);

         % Check for running singleton app
         if isempty(runningApp)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)
         else

            % Focus the running singleton app
            figure(runningApp.UIFigure)

            app = runningApp;
         end

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end