classdef VPT < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                       matlab.ui.Figure
      Panel                          matlab.ui.container.Panel
      CountsEditField                matlab.ui.control.NumericEditField
      countsEditFieldLabel           matlab.ui.control.Label
      DegreesEditField               matlab.ui.control.NumericEditField
      PositiondegreesEditFieldLabel  matlab.ui.control.Label
      ChairControlPanel              matlab.ui.container.Panel
      TestingButton                  matlab.ui.control.Button
      HomeButton                     matlab.ui.control.Button
      EnablechairButton              matlab.ui.control.Button
      DisablechairButton             matlab.ui.control.Button
      RLamp                          matlab.ui.control.Lamp
      RLampLabel                     matlab.ui.control.Label
      LLamp                          matlab.ui.control.Lamp
      LLampLabel                     matlab.ui.control.Label
      PauseButton                    matlab.ui.control.StateButton
      StopButton                     matlab.ui.control.StateButton
      TabGroup                       matlab.ui.container.TabGroup
      ProcedureTab                   matlab.ui.container.Tab
      TrialnumberEditField           matlab.ui.control.NumericEditField
      TrialnumberEditFieldLabel      matlab.ui.control.Label
      PausedurationsEditField        matlab.ui.control.NumericEditField
      PausedurationsEditFieldLabel   matlab.ui.control.Label
      RepetitionsEditField           matlab.ui.control.NumericEditField
      RepetitionsEditFieldLabel      matlab.ui.control.Label
      FrequencyHzEditField           matlab.ui.control.NumericEditField
      FrequencyHzEditFieldLabel      matlab.ui.control.Label
      NUpEditField                   matlab.ui.control.NumericEditField
      NUpEditFieldLabel              matlab.ui.control.Label
      MDownEditField                 matlab.ui.control.NumericEditField
      MDownEditFieldLabel            matlab.ui.control.Label
      StartvalueEditField            matlab.ui.control.NumericEditField
      StartvalueEditFieldLabel       matlab.ui.control.Label
      DirectionDropDown              matlab.ui.control.DropDown
      NoiseTab                       matlab.ui.container.Tab
      VolumeSlider                   matlab.ui.control.Slider
      VolumeSliderLabel              matlab.ui.control.Label
      PostEditField                  matlab.ui.control.NumericEditField
      PostsEditFieldLabel            matlab.ui.control.Label
      PreEditField                   matlab.ui.control.NumericEditField
      PresEditFieldLabel             matlab.ui.control.Label
      PlayCheckBox                   matlab.ui.control.CheckBox
      DisplayTab                     matlab.ui.container.Tab
      ProjectionPanel                matlab.ui.container.Panel
      WidthcmEditField               matlab.ui.control.NumericEditField
      WidthcmEditFieldLabel          matlab.ui.control.Label
      BackgroundcolorEditField       matlab.ui.control.EditField
      BackgroundcolorEditFieldLabel  matlab.ui.control.Label
      MonitorEditField               matlab.ui.control.NumericEditField
      MonitorEditFieldLabel          matlab.ui.control.Label
      DistancecmEditField            matlab.ui.control.NumericEditField
      DistancecmEditFieldLabel       matlab.ui.control.Label
      OpenButton                     matlab.ui.control.Button
      CloseButton                    matlab.ui.control.Button
      ImageEditField                 matlab.ui.control.EditField
      ImageButton                    matlab.ui.control.Button
      PsychtoolboxTab                matlab.ui.container.Tab
      LocationDropDown               matlab.ui.control.DropDown
      LocationDropDownLabel          matlab.ui.control.Label
      AddressEditField               matlab.ui.control.EditField
      PingButton                     matlab.ui.control.Button
      PingLamp                       matlab.ui.control.Lamp
      CodeEditField                  matlab.ui.control.EditField
      CodeEditFieldLabel             matlab.ui.control.Label
      DataFilePathTextField          matlab.ui.control.EditField
      DebugModeLabel                 matlab.ui.control.Label
      StartButton                    matlab.ui.control.Button
      FolderEditField                matlab.ui.control.EditField
      SubjectIDEditField             matlab.ui.control.EditField
      SubjectIDEditFieldLabel        matlab.ui.control.Label
      FolderButton                   matlab.ui.control.Button
      UITable                        matlab.ui.control.Table
   end


    properties (Access = private)
        backgroundColor         % Background color
        imagePath = ''          % Image to display

        imageTexture            % Psychtoolbox texture
        window = []             % Psychtoolbox window handle

        appState = 'idle'       % idle, active, paused
        currentTrialNumber      % as it says
        data = []               % ultimately a structure containing the data
        dataFilePath = '';      % path to the data

        pest = [];              % PEST state

        frameRate               % Frame rate measured by Psychtoolbox
        position_vs_time        % precomputed position vector
        lastPosition            % Current position
        currentResponse;        % Current response

        debugMode = false;      % For testing purposes
        version = "1.0"         % Version of this app
        audioPlayer             % Description

        daq_handle_write
        daq_handle_read
        abortMovement = false;

        encoderScale = 1 / 32768 / 4 * 360;
%         encoderScale = 1 / 30000 / 4 * 360;
        
        leftButton = 0
        rightButton = 0
        leftButtonFirst = 0

        homePosition = NaN;

        ledLitColor = [0 1 0];
        ledUnlitColor = [0 0.2 0];

        projection = []

        ptbLocation = 'Local';
        ptbRemote = struct('address', '', 'port', -1);
        remoteConnected = false;

        logger = [];
    end

    methods (Access = private)

        function success = InitializePsychtoolbox(app)
            if app.debugMode || ~isempty(app.window)
                success = true;
                return;
            end

            if isequal(app.ptbLocation, 'Remote')
               app.logger.LogInfo('Opening remote PTB');
   
               if ~app.remoteConnected
                  connected = app.ConnectToRemote();
                  if ~connected
                     success = false;
                     return;
                  end
               end
               result = app.SendMessage('Open', app.EncodeSettings());
               success = result > 0;

               app.OpenButton.Visible = ~success;
               return;
            end

            app.logger.LogInfo('Opening local PTB');
            screenNum = app.MonitorEditField.Value; % set screen

            appScreenNumber = get_app_screen_number(app.UIFigure);
            if appScreenNumber == screenNum
                app.logger.LogError('Psychtoolbox output would be displayed on same screen on this app.');
                msgbox('Psychtoolbox output would be displayed on same screen on this app. Move this app to another screen or change the image screen number', 'VPT Error', 'error');
                success = false;
                return;
            end

            PsychDefaultSetup(2);
            Screen('Preference', 'SkipSyncTests', 1);
            Screen('Preference', 'VisualDebugLevel', 3);
            app.window = Screen('OpenWindow', screenNum, app.backgroundColor);

            Screen('BlendFunction', app.window, 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA');
            img = imread(app.imagePath);
            imgSize = size(img);
            imageWidth = imgSize(2);
            imageHeight = imgSize(1);
            app.imageTexture = Screen('MakeTexture', app.window, img);

            Screen('DrawTexture', app.window, app.imageTexture);
            Screen('Flip', app.window);

            app.frameRate = FrameRate(app.window);

            resol = Screen('Resolution', screenNum);
            xcenter = round(resol.width / 2);
            ycenter = round(resol.height / 2);

            left = xcenter - round(imageWidth / 2);
            top = ycenter - round(imageHeight / 2);

            app.projection.defaultDestRect = [left top left + imageWidth top + imageHeight];
            app.projection.pixels_per_cm = resol.width / app.WidthcmEditField.Value;

            app.projection.imageWidth_cm = imageWidth / app.projection.pixels_per_cm;
            app.projection.theta = atan(app.projection.imageWidth_cm / app.WidthcmEditField.Value) * 180 / pi;
            app.projection.aspectRatio = imageHeight / imageWidth;

            success = true;
        end

        %--------------------------------------------------------------------
        function InitializeDAQ(app)
            app.daq_handle_write=daq('ni');
            app.daq_handle_read =daq('ni');
            if ~app.debugMode
                deviceName = 'dev1';

                NOTC_ChairVelocityCommand = addoutput(app.daq_handle_write, deviceName, "ao2", "Voltage");
                NOTC_PCready = addoutput(app.daq_handle_write, deviceName, 'port0/line8', "Digital");
                NOTC_RotationServo = addoutput(app.daq_handle_write, deviceName, 'port0/line13', "Digital");

                NOTC_ButtonLeft = addinput(app.daq_handle_read, deviceName, 'port0/line2', "Digital");
                NOTC_ButtonRight = addinput(app.daq_handle_read, deviceName, 'port0/line1', "Digital");
                NOTC_ChairPositionFeedback = addinput(app.daq_handle_read, deviceName, 'ctr0', "Position");
                NOTC_ChairVelocityFeedback = addinput(app.daq_handle_read, deviceName, "ai0", "Voltage"); %#ok<*NASGU>
                NOTC_HomePosition = addinput(app.daq_handle_read, deviceName, 'port0/line3', "Digital"); % This pulse goes high when the chair/subject faces the door

            else
                dev = 'PXI1Slot4';
                NOTC_ChairVelocityCommand = addoutput(app.daq_handle_write, dev, "ao0", "Voltage");
                NOTC_PCready = addoutput(app.daq_handle_write, dev, 'port0/line0', "Digital");
                NOTC_RotationServo = addoutput(app.daq_handle_write, dev, 'port0/line1', "Digital");

                NOTC_ButtonLeft = addinput(app.daq_handle_read, dev, 'port0/line5', "Digital");
                NOTC_ButtonRight = addinput(app.daq_handle_read, dev, 'port0/line6', "Digital");
                NOTC_ChairPositionFeedback = addinput(app.daq_handle_read, dev, 'ctr0', "Position");
                NOTC_ChairVelocityFeedback = addinput(app.daq_handle_read, dev, "ai0", "Voltage"); %#ok<*NASGU>
            end
            set(app.daq_handle_read.Channels(3), 'EncoderType', 'X4');
            app.daq_handle_read.ScansAvailableFcn = @(src,event) app.AcquireButtonData();
            app.daq_handle_read.ScansAvailableFcnCount = 100;

            start(app.daq_handle_read,'continuous');
        end

        %--------------------------------------------------------------------
        function AcquireButtonData(app)
            if app.daq_handle_read.NumScansAvailable > 0
                daq = read(app.daq_handle_read, 'all', 'OutputFormat', 'Matrix');

                if isnan(app.homePosition)
                    isHome = daq(:, end);
                    if any(isHome)
                        idx = find(isHome, 1);
                        encoderPosition = -daq(idx, 3);
                        angle = -encoderPosition * app.encoderScale;
                        app.homePosition = angle;
                        fprintf('Home position = %.1f degrees\n', app.homePosition);
                    end
                end

                leftIndex = find(daq(:, 1) > 0, 1);
                rightIndex = find(daq(:, 2) > 0, 1);

                app.leftButton = ~isempty(leftIndex);
                app.rightButton = ~isempty(rightIndex);
                if (app.leftButton && app.rightButton)
                    app.leftButtonFirst = leftIndex < rightIndex;
                end

                if app.leftButton
                    app.LLamp.Color = app.ledLitColor;
                else
                    app.LLamp.Color = app.ledUnlitColor;
                end
                if app.rightButton
                    app.RLamp.Color = app.ledLitColor;
                else
                    app.RLamp.Color = app.ledUnlitColor;
                end

                encoderPosition = daq(end,3);
                if encoderPosition > 2^31
                    encoderPosition = encoderPosition - 2^32;
                end
                app.CountsEditField.Value = -encoderPosition;
                app.DegreesEditField.Value = -encoderPosition * app.encoderScale;
            end
        end

        %--------------------------------------------------------------------
        function InitializeData(app)
            % Create data path
            startTime = datetime('now');
            fn = sprintf('%s_%s', char(startTime, 'yyyMMdd_HHmmss'), app.SubjectIDEditField.Value);
            if ~isempty(app.CodeEditField.Value)
                fn = [fn '_' app.CodeEditField.Value];
            end
            fn = [fn '.mat'];

            folder = fullfile(app.FolderEditField.Value, app.SubjectIDEditField.Value);
            if ~exist(folder, 'dir')
                mkdir(folder);
            end

            app.dataFilePath = fullfile(folder, fn);
            app.DataFilePathTextField.Value = fn;

            % Create data structure
            app.data = struct();

            % Fill out meta data structure
            app.data.info.date = startTime;
            app.data.info.subject = app.SubjectIDEditField.Value;
            app.data.info.code = app.CodeEditField.Value;
            app.data.info.filepath = app.dataFilePath;
            app.data.info.version = app.version;

            % Save settings
            app.data.settings.motionType = app.DirectionDropDown.Value;
            app.data.settings.frequency = app.FrequencyHzEditField.Value;
            app.data.settings.pauseDuration = app.PausedurationsEditField.Value;
            app.data.settings.startValue = app.StartvalueEditField.Value;
            app.data.settings.repetitions = app.RepetitionsEditField.Value;
            app.data.settings.screenDistance = app.DistancecmEditField.Value;
            app.data.settings.Mdown = app.MDownEditField.Value;

            % Create table
            % Randomize direction vector
            Direction = [ones(round(app.data.settings.repetitions/2), 1); -ones(round(app.data.settings.repetitions/2), 1)];
            Direction = Direction(randperm(length(Direction)));

            Position_deg = NaN(size(Direction));
            Position_cm = Position_deg;
            Response = Position_deg;

            Position_deg(1) = app.data.settings.startValue;

            app.data.table = table(Position_deg, Position_cm, Direction, Response);
            app.UITable.RowName = num2str((1:length(Direction))');

            % Save
            data = app.data; %#ok<ADPROP>
            save(app.dataFilePath, 'data');
        end

        %--------------------------------------------------------------------
        function InitializeMotion(app)
            if app.debugMode, return; end

            if isequal(app.ptbLocation, 'Remote')
               settings = struct( ...
                  'frequency', app.data.settings.frequency, ...
                  'pre', app.PreEditField.Value, ...
                  'post', app.PostEditField.Value);
               result = app.SendMessage('InitializeMotion', jsonencode(settings));
               if result <= 0
                  app.logger.LogError('failed to initialize remote motion');
                  error('failed to initialize motion');
               end
               return;
            end

            freq = app.data.settings.frequency;
            T = 1 / freq;
            dt = 1 / app.frameRate;

            t = 0:dt:T;

            app.position_vs_time = freq * (t - sin(2*pi*freq*t)/(2*pi*freq));

            app.lastPosition = 0;
        end

        %--------------------------------------------------------------------
        function FindHome(app)
            app.HomeButton.Enable = 'off';
            app.TestingButton.Enable = 'off';
            app.StartButton.Enable = false;

            Amax = 100; % degrees/s^2

            numspins = 0;
            app.abortMovement = false;
            
            while isnan(app.homePosition) && numspins < 4 && ~app.abortMovement
                daq = read(app.daq_handle_read);
                if height(daq) > 0
                    returnCounts = daq{end,3};
                    if returnCounts > 2^31
                        returnCounts = returnCounts - 2^32;
                    end
                    encoderPos = -returnCounts;
                    currentAngle = -encoderPos * app.encoderScale;

                    app.CountsEditField.Value = encoderPos;
                    app.DegreesEditField.Value = currentAngle;

                    targetAngle = currentAngle + 90;
                    currentDelta = currentAngle - targetAngle;

                    return_time = sqrt(2*pi*abs(currentDelta) / Amax);

                    f = 1 / return_time;
                    Vmax = sign(currentDelta) * Amax / (pi * f);

                    dt = 1 / app.daq_handle_write.Rate;

                    treturn = 0:dt:return_time;

                    return_trajectory = [ ...
                        0.5 * Vmax / 40 * (1 - cos(2*pi*f*treturn(:))); ...
                        zeros(round(0.1/dt), 1) ...
                        ];

                    n_samples_return = length(return_trajectory);

                    if n_samples_return > 0
                        outScanData = [
                            return_trajectory, ... ChairVelocityCommand
                            ones(n_samples_return,1), ... PCready
                            ones(n_samples_return,1) ... RotationServo
                            ];
                        write(app.daq_handle_write, outScanData);
                        
                        numspins = numspins + 1;
                    end
                end
            end
            pause(1);

            app.HomeButton.Enable = 'on';
            app.TestingButton.Enable = 'on';
            app.StartButton.Enable = true;
        end

        %--------------------------------------------------------------------
        function RotateChair(app, targetAngle)
            app.HomeButton.Enable = 'off';
            app.TestingButton.Enable = 'off';
            app.StartButton.Enable = false;

            Amax = 100; % degrees/s^2
            minDelta = 2;

            inPosition = false;
            app.abortMovement = false;

            while ~inPosition && ~app.abortMovement
                daq = read(app.daq_handle_read);
                if height(daq) > 0
                    returnCounts = daq{end,3};
                    if returnCounts > 2^31
                        returnCounts = returnCounts - 2^32;
                    end
                    encoderPos = -returnCounts;
                    currentAngle = -encoderPos * app.encoderScale;

                    app.CountsEditField.Value = encoderPos;
                    app.DegreesEditField.Value = currentAngle;

                    currentDelta = (currentAngle - app.homePosition) - targetAngle;

                    if abs(currentDelta) <= minDelta
                        inPosition = true;
                    else
                        return_time = sqrt(2*pi*abs(currentDelta) / Amax);

                        f = 1 / return_time;
                        Vmax = sign(currentDelta) * Amax / (pi * f);

                        dt = 1 / app.daq_handle_write.Rate;

                        fprintf('return time = %f\n', return_time);
                        treturn = 0:dt:return_time;

                        return_trajectory = [ ...
                            0.5 * Vmax / 40 * (1 - cos(2*pi*f*treturn(:))); ...
                            zeros(round(0.1/dt), 1) ...
                            ];

                        n_samples_return = length(return_trajectory);

                        if n_samples_return > 0
                            outScanData = [
                                return_trajectory, ... ChairVelocityCommand
                                ones(n_samples_return,1), ... PCready
                                ones(n_samples_return,1) ... RotationServo
                                ];
                            write(app.daq_handle_write, outScanData);
                        end
                    end
                end
            end
            pause(1);

            app.HomeButton.Enable = 'on';
            app.TestingButton.Enable = 'on';
            app.StartButton.Enable = true;

        end

        %--------------------------------------------------------------------
        function StartAudio(app)
            Fs = 44100;
            T = 30;
            nsamples = round(Fs * T);
            whitenoise = normrnd(0, 0.2, nsamples, 1);
            app.audioPlayer = audioplayer(whitenoise, Fs);
            app.audioPlayer.StopFcn = createCallbackFcn(app, @AudioPlayerStopFcn, true);
            play(app.audioPlayer);
        end

        %--------------------------------------------------------------------
        function StopAudio(app)
            app.audioPlayer.StopFcn = [];
            stop(app.audioPlayer);
        end

        %--------------------------------------------------------------------
        function AudioPlayerStopFcn(app, ~)
            play(app.audioPlayer);
        end

        %--------------------------------------------------------------------
        function DoRun(app)

            try
                startTrial = app.currentTrialNumber;

                for k = startTrial : app.data.settings.repetitions
                    app.currentTrialNumber = k;
                    app.TrialnumberEditField.Value = k;

                    app.UITable.Data = app.data.table(1:app.currentTrialNumber, :);
                    scroll(app.UITable, 'bottom');
                    drawnow;
                    pause(0.1);

                    app.currentResponse = 0;

                    currentTrial = app.data.table(app.currentTrialNumber, :);

                    if ~app.debugMode

                        if isequal(app.data.settings.motionType, 'Up / Down')
                            app.DoUpDown(currentTrial.Position_deg, currentTrial.Direction);
                        elseif isequal(app.data.settings.motionType, 'Left / Right')
                            app.DoLeftRight(currentTrial.Position_deg, currentTrial.Direction);
                        elseif isequal(app.data.settings.motionType, 'Roll')
                            app.DoRotation(currentTrial.Position_deg, currentTrial.Direction);
                        elseif isequal(app.data.settings.motionType, 'Zoom')
                            app.DoZoom(currentTrial.Position_deg, currentTrial.Direction);
                        end
                        % response = app.WaitForResponse();
                        response = app.SimulateResponse(currentTrial.Position_deg, currentTrial.Direction);
                        pause(2);
                    else
                        response = app.SimulateResponse(currentTrial.Position_deg, currentTrial.Direction);
                    end

                    if k == 1
                        % Zoom can't be computed until psychtoolbox is
                        % initialized
                        app.data.table.Position_cm(app.currentTrialNumber) = ConvertDegreesToCm(app, app.data.table.Position_deg(app.currentTrialNumber));
                        app.UITable.Data(k, 2) = {app.data.table.Position_cm(app.currentTrialNumber)};
                    end

                    app.data.table.Response(k) = response;
                    app.UITable.Data(k, 4) = {response};
                    drawnow;

                    if app.currentTrialNumber < app.data.settings.repetitions
                        newValue = app.ProcessResponse(currentTrial.Position_deg, currentTrial.Direction, response);
                        app.data.table.Position_deg(app.currentTrialNumber + 1) = newValue;
                        app.data.table.Position_cm(app.currentTrialNumber + 1) = ConvertDegreesToCm(app, newValue);
                    end

                    data = app.data; %#ok<ADPROP>
                    save(app.dataFilePath, 'data');

                    startWait = tic();
                    done = app.debugMode;
                    while ~done
                        pause(0.2);
                        if toc(startWait) >= app.data.settings.pauseDuration
                            done = true;
                        end

                        if app.StopButton.Value > 0, break; end
                        if app.PauseButton.Value > 0, break; end
                    end

                    app.currentTrialNumber = k + 1;

                    if app.StopButton.Value > 0, break; end
                    if app.PauseButton.Value > 0, break; end
                end
            catch ex
                disp(ex.message);
                for k = 1:length(ex.stack)
                    disp(ex.stack(k));
                end
                msgbox(ex.message, 'VPT Error', 'error');
            end

            if app.PauseButton.Value > 0
                app.StopButton.Enable = false;
                app.StopButton.Value = 0;
                app.PauseButton.Text = "PAUSED";
                app.PauseButton.BackgroundColor = [1 1 0];
            else
                app.EndRun();
            end
        end

        %--------------------------------------------------------------------
        function DoRotation(app, delta_position, direction)

           if isequal(app.ptbLocation, 'Remote')
              settings = struct( ...
                 'delta', delta_position, ...
                 'direction', direction);
              result = app.SendMessage('DoRotation', jsonencode(settings));
              if result < 0
                 app.logger.LogError('failed to do remote rotation');
                 error('failed to do rotation');
              end
              return;
           end

           x = direction * delta_position * app.position_vs_time + app.lastPosition;

           for k = 1:length(x)
              Screen('DrawTexture', app.window, app.imageTexture, [], [], x(k));
              Screen('Flip', app.window);
           end
           app.lastPosition = x(end);
        end

        %--------------------------------------------------------------------
        function DoLeftRight(app, delta_position, direction)
           if isequal(app.ptbLocation, 'Remote')
              settings = struct( ...
                 'delta', delta_position, ...
                 'direction', direction);
              result = app.SendMessage('DoLeftRight', jsonencode(settings));
              if result < 0
                 app.logger.LogError('failed to do left/right movement');
                 error('failed to do movement');
              end
              return;
           end

           x = direction * delta_position * app.position_vs_time + app.lastPosition;

            for k = 1:length(x)
                Screen('DrawTexture', app.window, app.imageTexture, [], app.projection.defaultDestRect + x(k) * app.projection.pixels_per_cm * [1 0 1 0]);
                Screen('Flip', app.window);
            end
            app.lastPosition = x(end);
        end

        %--------------------------------------------------------------------
        function DoUpDown(app, delta_position, direction)
           if isequal(app.ptbLocation, 'Remote')
              settings = struct( ...
                 'delta', delta_position, ...
                 'direction', direction);
              result = app.SendMessage('DoUpDown', jsonencode(settings));
              if result < 0
                 app.logger.LogError('failed to do up/down movement');
                 error('failed to do movement');
              end
              return;
           end

           y = -direction * delta_position * app.position_vs_time + app.lastPosition;

            for k = 1:length(y)
                Screen('DrawTexture', app.window, app.imageTexture, [], app.projection.defaultDestRect + y(k) * app.projection.pixels_per_cm * [0 1 0 1]);
                Screen('Flip', app.window);
            end
            app.lastPosition = y(end);
        end

        %--------------------------------------------------------------------
        function DoZoom(app, delta_position, direction)
           if isequal(app.ptbLocation, 'Remote')
              settings = struct( ...
                 'delta', delta_position, ...
                 'direction', direction);
              result = app.SendMessage('DoZoom', jsonencode(settings));
              if result < 0
                 app.logger.LogError('failed to do zoom');
                 error('failed to do zoom');
              end
              return;
           end

            delta_x_cm = app.data.settings.screenDistance * tan((app.projection.theta + direction * delta_position) * pi/180) - app.projection.imageWidth_cm;
            delta_x_pixels = delta_x_cm * app.projection.pixels_per_cm;

            x = delta_x_pixels * app.position_vs_time + app.lastPosition;

            for k = 1:length(x)
                destRect = app.projection.defaultDestRect + x(k) * [-1 -app.projection.aspectRatio 1 app.projection.aspectRatio];

                Screen('DrawTexture', app.window, app.imageTexture, [], destRect);
                Screen('Flip', app.window);
            end
            disp((x(3)-x(1))/ (x(4) - x(2)));
            app.lastPosition = x(end);
        end

        %--------------------------------------------------------------------
        function response = WaitForResponse(app)
            response = 0;
            while response == 0
                if (app.leftButton || app.rightButton)
                    if app.leftButton && (~app.rightButton || app.leftButtonFirst)
                        response = -1;
                    else
                        response = 1;
                    end
                    break;
                end
                pause(0.1);
            end
        end

        %--------------------------------------------------------------------
        function EndRun(app)
            app.PauseButton.Enable = false;
            app.StopButton.Enable = false;

            app.PauseButton.Value = 0;
            app.StopButton.Value = 0;

            app.StartButton.Enable = true;
            app.HomeButton.Enable = 'on';
            app.TestingButton.Enable = 'on';
        end

        %--------------------------------------------------------------------
        function newValue = ProcessResponse(app, lastValue, direction, response)

            correctResponse = direction == response;

            newValue = lastValue;

            if (correctResponse)
                % Correct
                app.pest.n_correct = app.pest.n_correct + 1;
                if app.pest.n_correct == app.data.settings.Mdown
                    app.pest.n_correct = 0;
                    if (app.pest.staircase_direction == 1)
                        % Reversal from up to down
                        app.pest.step_size = sqrt(app.pest.step_size);
                        app.pest.step_size = max(app.pest.step_size, 1.004);
                        app.pest.steps_since_last_reversal = 1;
                        app.pest.doubling_preceded_last_reversal = app.pest.last_step_was_doubling;
                        app.pest.last_step_was_doubling = 0;

                    elseif (app.pest.staircase_direction==0)
                        % Continuing to go down
                        app.pest.steps_since_last_reversal = app.pest.steps_since_last_reversal + 1;
                        if (app.pest.steps_since_last_reversal==1) || (app.pest.steps_since_last_reversal==2)
                            % Do not change step size
                            app.pest.last_step_was_doubling = 0;
                        elseif (app.pest.steps_since_last_reversal>3) || ((app.pest.steps_since_last_reversal==3) && ~app.pest.doubling_preceded_last_reversal )
                            app.pest.step_size = app.pest.step_size^2;
                            app.pest.step_size = min(app.pest.step_size, 2);
                            app.pest.last_step_was_doubling = 1;
                        end
                    end
                    newValue = lastValue / app.pest.step_size;
                    app.pest.staircase_direction = 0;
                else
                    % no change
                end

            else
                % Incorrect
                app.pest.n_correct = 0;
                if (app.pest.staircase_direction == 0)
                    % Reversal from down to up
                    app.pest.step_size = sqrt(app.pest.step_size);
                    app.pest.step_size = max(app.pest.step_size, 1.004);
                    app.pest.steps_since_last_reversal = 1;
                    app.pest.doubling_preceded_last_reversal = app.pest.last_step_was_doubling;
                    app.pest.last_step_was_doubling = 0;

                elseif (app.pest.staircase_direction == 1)
                    % Continuing to go up
                    app.pest.steps_since_last_reversal = app.pest.steps_since_last_reversal + 1;
                    if (app.pest.steps_since_last_reversal==1) || (app.pest.steps_since_last_reversal==2)
                        % Do not change step size
                        app.pest.last_step_was_doubling = 0;
                    elseif (app.pest.steps_since_last_reversal>3) || ((app.pest.steps_since_last_reversal==3) && ~app.pest.doubling_preceded_last_reversal )
                        app.pest.step_size = app.pest.step_size^2;
                        app.pest.step_size = min(app.pest.step_size,2);
                        app.pest.last_step_was_doubling = 1;
                    end
                end
                newValue = lastValue * app.pest.step_size;
                app.pest.staircase_direction = 1;
            end
        end

        %--------------------------------------------------------------------
        function response = SimulateResponse(~, value, direction)

            p = normcdf(value, 0.15, 0.01);
            if rand < p
                response = direction;
            else
                response = -direction;
            end
        end

        %--------------------------------------------------------------------
        function cm = ConvertDegreesToCm(app, angle)
            switch app.data.settings.motionType
                case { 'Up / Down', 'Left / Right'}
                    cm = app.data.settings.screenDistance * tan(angle * pi/180);
                case 'Roll'
                    cm = NaN;
                case 'Zoom'
                    cm = app.data.settings.screenDistance - app.projection.imageWidth_cm / tan((angle + app.projection.theta)*pi/180);
            end
        end

        %--------------------------------------------------------------------
        function ClearPingLamp(app, timerObj, ~)
           stop(timerObj);
           delete(timerObj);
           app.PingLamp.Color = app.ledUnlitColor;
        end

        %--------------------------------------------------------------------
        function connected = ConnectToRemote(app)
           connected = false;
           app.logger.LogInfo('Connecting to remote');
           [address, port] = net.Discover('VPT.INTERFACE');
           if ~isempty(address)
              app.logger.LogInfo(sprintf('discovered PTB interface at %s:%d', address, port));
              app.ptbRemote = struct('address', address, 'port', port);
              app.AddressEditField.Value = sprintf('%s:%d', address, port);
              connected = true;
           else
              app.logger.LogError('did not discover PTB interface');
           end
           app.remoteConnected = connected;
        end
      
        %--------------------------------------------------------------------
        % function ApplyRemoteProjectionSettings(app, settings)
        %    app.imagePath = settings.imageName;
        %    app.ImageEditField.Value = settings.imageName;
        %    setpref('VPT', 'ImagePath', settings.imageName);
        % 
        %    app.backgroundColor = settings.backgroundColor;
        %    app.BackgroundcolorEditField.Value = sprintf('%d, %d, %d', app.backgroundColor);
        %    setpref('VPT', 'BackgroundColor', settings.backgroundColor);
        % 
        %    app.MonitorEditField.Value = settings.monitor;
        %    setpref('VPT', 'Monitor', settings.monitor);
        % 
        %    app.DistancecmEditField.Value = settings.distance;
        %    setpref('VPT', 'Distance', settings.distance);
        % 
        %    app.WidthcmEditField.Value = settings.width;
        %    setpref('VPT', 'Width', settings.width);
        % end

        %--------------------------------------------------------------------
        function jsonString = EncodeSettings(app)
           [~, fn, ext] = fileparts(app.imagePath);
           settings = struct( ...
              'imageName', [fn ext], ...
              'backgroundColor', app.backgroundColor, ...
              'distance', app.DistancecmEditField.Value, ...
              'width', app.WidthcmEditField.Value, ...
              'monitor', app.MonitorEditField.Value);

           jsonString = jsonencode(settings);
        end

        %--------------------------------------------------------------------
        function result = SendMessage(app, message, data)
           if nargin < 3, data = ''; end
           app.logger.LogInfo(sprintf('Send message ''%s''', message));
           result = net.SendMessage(app.ptbRemote.address, app.ptbRemote.port, message, data);
        end
    end

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
            app.DebugModeLabel.Visible = app.debugMode;

            app.LLamp.Color = app.ledUnlitColor;
            app.RLamp.Color = app.ledUnlitColor;
            app.PingLamp.Color = app.ledUnlitColor;

            folder = fullfile(fileparts(userpath), 'Jenks', 'VPT', 'Logs');
            if ~exist(folder, 'dir')
               mkdir(folder);
            end
            app.logger = epl.Logger(folder, 'VPT');
            app.logger.LogInfo('VPT started');

            % Restore data folder
            app.FolderEditField.Value = getpref('VPT', 'RootDataFolder', pwd);

            % Restore image path
            app.imagePath = getpref('VPT', 'ImagePath', '');
            if ~isempty(app.imagePath)
                [~, fn, ext] = fileparts(app.imagePath);
                app.ImageEditField.Value = [fn ext];
            end

            % Restore background color
            app.backgroundColor = getpref('VPT', 'BackgroundColor', [0 0 0]);
            app.BackgroundcolorEditField.Value = sprintf('%d, %d, %d', app.backgroundColor);

            app.MonitorEditField.Value = getpref('VPT', 'MonitorNumber', 2);
            app.DistancecmEditField.Value = getpref('VPT', 'Distance', 100);
            app.WidthcmEditField.Value = getpref('VPT', 'Width', 100);

            app.ptbLocation = getpref('VPT', 'PTBLocation', 'Local');
            if ~ismember(app.ptbLocation, {'Local', 'Remote'})
               app.ptbLocation = 'Local';
            end
            app.LocationDropDown.Value = app.ptbLocation;

            % Restore noise properties
            app.PreEditField.Value = getpref('VPT', 'NoisePreDuration', 0.5);
            app.PostEditField.Value = getpref('VPT', 'NoisePostDuration', 0.2);
            app.VolumeSlider.Value = 0;

            % Initialize DAQ
            % app.InitializeDAQ();
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
            app.logger.LogInfo('VPT closing');
            app.logger.Flush();

            if ~isempty(app.window)
                Screen('CloseAll');
            end

            delete(app)
      end

      % Button pushed function: FolderButton
      function FolderButtonPushed(app, event)
            selection = uigetdir(app.FolderEditField.Value);
            if ~isempty(selection) && ~isequal(selection, 0)
                app.FolderEditField.Value = selection;
                setpref('VPT', 'RootDataFolder', selection)
            end
      end

      % Value changed function: FolderEditField
      function FolderEditFieldValueChanged(app, event)
            setpref('VPT', 'RootDataFolder', app.FolderEditField.Value);
      end

      % Button pushed function: ImageButton
      function ImageButtonPushed(app, event)
            [selection, folder] = uigetfile({'*.png;Image files}'}, 'Select an image', app.imagePath);
            if ~isempty(selection) && ~isequal(selection, 0)
                app.imagePath = fullfile(folder, selection);
                app.ImageEditField.Value = selection;
                setpref('VPT', 'ImagePath', app.imagePath);
            end
      end

      % Value changed function: ImageEditField
      function ImageEditFieldValueChanged(app, event)
            value = app.ImageEditField.Value;
            selection = fullfile(fileparts(app.imagePath), value);
            if exist(selection, 'file')
                app.ImagePath = selection;
                setpref('VPT', 'ImagePath', selection)
            else
                [~, fn, ext] = fileparts(app.imagePath);
                app.ImageEditField.Value = [fn ext];
            end
      end

      % Button pushed function: StartButton
      function StartButtonPushed(app, event)
            app.StartButton.Enable = false;
            try

                ptb_initialized = app.InitializePsychtoolbox(); % just to be sure, will skip if already initialized
                if ~ptb_initialized
                    app.logger.LogError('failed to initialize PTB');
                    app.StartButton.Enable = true;
                    return;
                end

                app.InitializeData();
                app.InitializeMotion();

                if isequal(app.ptbLocation, 'Remote')
                   app.SendMessage('Volume', num2str(app.VolumeSlider.Value/100));
                end

                % initialize bookkeeping
                app.currentTrialNumber = 1;

                app.pest.step_size=2;
                app.pest.steps_since_last_reversal=0;
                app.pest.staircase_direction=-1; % -1 not used; 0 going down; 1 going up
                app.pest.doubling_preceded_last_reversal=0; % 0 false; 1 true
                app.pest.last_step_was_doubling=0; % 0 false; 1 true
                app.pest.n_correct=0;

                % enable controls
                app.PauseButton.Enable = true;
                app.StopButton.Enable = true;
                app.HomeButton.Enable = 'off';
                app.TestingButton.Enable = 'off';
            catch ex
                for k = 1:length(ex.stack)
                    disp(ex.stack(k));
                end
                msgbox(ex.message, 'VPT error', 'error');
                app.StartButton.Enable = true;
            end

            % ...and go!
            app.DoRun();

      end

      % Button pushed function: OpenButton
      function OpenButtonPushed(app, event)
         ptb_initialized = app.InitializePsychtoolbox();
         app.OpenButton.Visible = ~ptb_initialized;
         if ~ptb_initialized
            app.logger.LogError('failed to open PTB display');
            msgbox('Failed to open PTB display', 'Error', 'error');
         end
      end

      % Button pushed function: CloseButton
      function CloseButtonPushed(app, event)
         if isequal(app.ptbLocation, 'Remote')
            app.logger.LogInfo('Closing remote PTB');
            app.SendMessage('Close');
         else
            app.logger.LogInfo('Closing local PTB');
            Screen('CloseAll');
            app.window = [];
         end
         app.OpenButton.Visible = true;
      end

      % Value changed function: MonitorEditField
      function MonitorEditFieldValueChanged(app, event)
            setpref('VPT', 'MonitorNumber', app.MonitorEditField.Value);
      end

      % Value changed function: PauseButton
      function PauseButtonPushed(app, event)
            if app.PauseButton.Value == 0
                app.PauseButton.BackgroundColor = [0.96 0.96 0.96];
                app.PauseButton.Text = "Pause";
                app.StopButton.Enable = true;
                app.DoRun();
            end
      end

      % Value changed function: BackgroundcolorEditField
      function BackgroundcolorEditFieldValueChanged(app, event)
            value = app.BackgroundcolorEditField.Value;
            bg = sscanf(value, '%d,%d,%d');
            if length(bg) == 3 && all(bg >=0 & bg <=255)
                app.backgroundColor = bg;
                setpref('VPT', 'BackgroundColor', bg);
            else
                app.BackgroundcolorEditField.Value = sprintf('%d, %d, %d', app.backgroundColor);
            end
      end

      % Button pushed function: EnablechairButton
      function EnablechairButtonPushed(app, event)
            write(app.daq_handle_write, [0 1 1]);
            app.EnablechairButton.Visible = false;
            app.HomeButton.Enable = 'on';
            app.TestingButton.Enable = 'on';
      end

      % Button pushed function: DisablechairButton
      function DisablechairButtonPushed(app, event)
            app.abortMovement = true;
            while app.daq_handle_write.Running
                pause(0.1);
            end
            write(app.daq_handle_write, [0 0 0]);
            app.EnablechairButton.Visible = true;
            app.HomeButton.Enable = 'off';
            app.TestingButton.Enable = 'off';
      end

      % Value changed function: DistancecmEditField
      function DistancecmEditFieldValueChanged(app, event)
            value = app.DistancecmEditField.Value;
            setpref('VPT', 'Distance', value);
      end

      % Value changed function: WidthcmEditField
      function WidthcmEditFieldValueChanged(app, event)
            value = app.WidthcmEditField.Value;
            setpref('VPT', 'Width', value);
      end

      % Button pushed function: HomeButton
      function HomeButtonPushed(app, event)
            if isnan(app.homePosition)
                app.FindHome();
            end
            app.RotateChair(0);
      end

      % Button pushed function: TestingButton
      function TestingButtonPushed(app, event)
            app.RotateChair(180);
      end

      % Button pushed function: PingButton
      function PingButtonPushed(app, event)
         [address, port] = net.Discover('VPT.INTERFACE');
         if ~isempty(address)
            app.logger.LogInfo(sprintf('discovered PTB interface at %s:%d', address, port));
            app.ptbRemote = struct('address', address, 'port', port);
            app.AddressEditField.Value = sprintf('%s:%d', address, port);
            result = app.SendMessage('Ping');
            if result > 0
               app.PingLamp.Color = app.ledLitColor;
               t = timer;
               t.StartDelay = 2;
               t.TimerFcn = @(timer_obj, event) app.ClearPingLamp(t, event);
               start(t);
            else
               app.logger.LogError('failed to ping PTB interface');
               msgbox('Failed to ping remote PTB interface', 'Ping', 'error');
            end
         else
            app.logger.LogError('failed to discover PTB interface');
         end
      end

      % Value changed function: LocationDropDown
      function LocationDropDownValueChanged(app, event)
         app.ptbLocation = app.LocationDropDown.Value;
         setpref('VPT', 'PTBLocation', app.ptbLocation);
         if ~isequal(app.ptbLocation, 'Remote')
            app.remoteConnected = false;
         end
      end

      % Button down function: DisplayTab
      function DisplayTabButtonDown(app, event)
         % if isequal(app.ptbLocation, "Remote") % && ~app.remoteConnected
         %    app.ProjectionPanel.Enable = 'off';
         %    connected = app.ConnectToRemote();
         %    if connected
         %       fprintf('%s Send message ''%s''\n', datestr(now, 'YYYY-mm-dd HH:MM:SS.fff'), 'GetProjectionSettings');
         %       response = net.SendMessageReceiveString(app.ptbRemote.address, app.ptbRemote.port, 'GetProjectionSettings');
         %       if ~isempty(response)
         %          settings = jsondecode(response);
         %          app.ApplyRemoteProjectionSettings(settings);
         %       else
         %          msgbox('RPC failed', 'Error', 'error');
         %       end
         %    end
         %    app.ProjectionPanel.Enable = 'on';
         % end
      end

      % Value changed function: PlayCheckBox
      function PlayCheckBoxValueChanged(app, event)
         if app.PlayCheckBox.Value
            if isequal(app.ptbLocation, 'Remote')
               app.SendMessage('Noise', 'on');
            else
               app.StartAudio();
            end
         else
            if isequal(app.ptbLocation, 'Remote')
               app.SendMessage('Noise', 'off');
            else
               app.StopAudio();
            end
         end
      end

      % Value changed function: VolumeSlider
      function VolumeSliderValueChanged(app, event)
         value = app.VolumeSlider.Value;
         if isequal(app.ptbLocation, 'Remote')
            app.SendMessage('Volume', num2str(value/100));
         end
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'VPT';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.UITable = uitable(app.UIFigure);
         app.UITable.ColumnName = {'A (deg)'; 'A (cm)'; 'Direction'; 'Response'};
         app.UITable.RowName = {};
         app.FolderButton = uibutton(app.UIFigure, 'push');
         app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
         app.FolderButton.Text = 'Folder';
         app.SubjectIDEditFieldLabel = uilabel(app.UIFigure);
         app.SubjectIDEditFieldLabel.Text = 'Subject ID';
         app.SubjectIDEditField = uieditfield(app.UIFigure, 'text');
         app.SubjectIDEditField.Value = 'dummy';
         app.FolderEditField = uieditfield(app.UIFigure, 'text');
         app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
         app.FolderEditField.Value = 'dummy';
         app.StartButton = uibutton(app.UIFigure, 'push');
         app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
         app.StartButton.Text = 'Start';
         app.DebugModeLabel = uilabel(app.UIFigure);
         app.DebugModeLabel.Visible = 'off';
         app.DebugModeLabel.Text = 'Debug mode';
         app.DataFilePathTextField = uieditfield(app.UIFigure, 'text');
         app.DataFilePathTextField.Editable = 'off';
         app.CodeEditFieldLabel = uilabel(app.UIFigure);
         app.CodeEditFieldLabel.Text = 'Code';
         app.CodeEditField = uieditfield(app.UIFigure, 'text');
         app.TabGroup = uitabgroup(app.UIFigure);
         app.ProcedureTab = uitab(app.TabGroup);
         app.ProcedureTab.Title = 'Procedure';
         app.DirectionDropDown = uidropdown(app.ProcedureTab);
         app.DirectionDropDown.Items = {'Up / Down', 'Left / Right', 'Roll', 'Zoom'};
         app.DirectionDropDown.Value = 'Up / Down';
         app.StartvalueEditFieldLabel = uilabel(app.ProcedureTab);
         app.StartvalueEditFieldLabel.Text = 'Start value';
         app.StartvalueEditField = uieditfield(app.ProcedureTab, 'numeric');
         app.StartvalueEditField.Value = 2;
         app.MDownEditFieldLabel = uilabel(app.ProcedureTab);
         app.MDownEditFieldLabel.Text = 'M-Down';
         app.MDownEditField = uieditfield(app.ProcedureTab, 'numeric');
         app.MDownEditField.Limits = [1 Inf];
         app.MDownEditField.RoundFractionalValues = 'on';
         app.MDownEditField.ValueDisplayFormat = '%.0f';
         app.MDownEditField.Value = 4;
         app.NUpEditFieldLabel = uilabel(app.ProcedureTab);
         app.NUpEditFieldLabel.Text = 'N-Up';
         app.NUpEditField = uieditfield(app.ProcedureTab, 'numeric');
         app.NUpEditField.Limits = [1 Inf];
         app.NUpEditField.RoundFractionalValues = 'on';
         app.NUpEditField.ValueDisplayFormat = '%.0f';
         app.NUpEditField.Value = 1;
         app.FrequencyHzEditFieldLabel = uilabel(app.ProcedureTab);
         app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
         app.FrequencyHzEditField = uieditfield(app.ProcedureTab, 'numeric');
         app.FrequencyHzEditField.Limits = [0.05 5];
         app.FrequencyHzEditField.Value = 1;
         app.RepetitionsEditFieldLabel = uilabel(app.ProcedureTab);
         app.RepetitionsEditFieldLabel.Text = 'Repetitions';
         app.RepetitionsEditField = uieditfield(app.ProcedureTab, 'numeric');
         app.RepetitionsEditField.Limits = [1 Inf];
         app.RepetitionsEditField.RoundFractionalValues = 'on';
         app.RepetitionsEditField.ValueDisplayFormat = '%.0f';
         app.RepetitionsEditField.Value = 100;
         app.PausedurationsEditFieldLabel = uilabel(app.ProcedureTab);
         app.PausedurationsEditFieldLabel.Text = 'Pause duration (s)';
         app.PausedurationsEditField = uieditfield(app.ProcedureTab, 'numeric');
         app.PausedurationsEditField.Limits = [0 Inf];
         app.PausedurationsEditField.Value = 2;
         app.TrialnumberEditFieldLabel = uilabel(app.ProcedureTab);
         app.TrialnumberEditFieldLabel.Text = 'Trial number';
         app.TrialnumberEditField = uieditfield(app.ProcedureTab, 'numeric');
         app.TrialnumberEditField.Editable = 'off';
         app.NoiseTab = uitab(app.TabGroup);
         app.NoiseTab.Title = 'Noise';
         app.PlayCheckBox = uicheckbox(app.NoiseTab);
         app.PlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlayCheckBoxValueChanged, true);
         app.PlayCheckBox.Text = 'Play';
         app.PresEditFieldLabel = uilabel(app.NoiseTab);
         app.PresEditFieldLabel.Text = 'Pre (s)';
         app.PreEditField = uieditfield(app.NoiseTab, 'numeric');
         app.PreEditField.Limits = [0 Inf];
         app.PreEditField.Value = 2;
         app.PostsEditFieldLabel = uilabel(app.NoiseTab);
         app.PostsEditFieldLabel.Text = 'Post (s)';
         app.PostEditField = uieditfield(app.NoiseTab, 'numeric');
         app.PostEditField.Limits = [0 Inf];
         app.PostEditField.Value = 2;
         app.VolumeSliderLabel = uilabel(app.NoiseTab);
         app.VolumeSliderLabel.Text = 'Volume';
         app.VolumeSlider = uislider(app.NoiseTab);
         app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
         app.DisplayTab = uitab(app.TabGroup);
         app.DisplayTab.Title = 'Display';
         app.DisplayTab.ButtonDownFcn = createCallbackFcn(app, @DisplayTabButtonDown, true);
         app.ProjectionPanel = uipanel(app.DisplayTab);
         app.ImageButton = uibutton(app.ProjectionPanel, 'push');
         app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
         app.ImageButton.Text = 'Image';
         app.ImageEditField = uieditfield(app.ProjectionPanel, 'text');
         app.ImageEditField.ValueChangedFcn = createCallbackFcn(app, @ImageEditFieldValueChanged, true);
         app.CloseButton = uibutton(app.ProjectionPanel, 'push');
         app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
         app.CloseButton.Text = 'Close';
         app.OpenButton = uibutton(app.ProjectionPanel, 'push');
         app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
         app.OpenButton.Text = 'Open';
         app.DistancecmEditFieldLabel = uilabel(app.ProjectionPanel);
         app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
         app.DistancecmEditField = uieditfield(app.ProjectionPanel, 'numeric');
         app.DistancecmEditField.Limits = [0 Inf];
         app.DistancecmEditField.ValueChangedFcn = createCallbackFcn(app, @DistancecmEditFieldValueChanged, true);
         app.DistancecmEditField.Value = 100;
         app.MonitorEditFieldLabel = uilabel(app.ProjectionPanel);
         app.MonitorEditFieldLabel.Text = 'Monitor';
         app.MonitorEditField = uieditfield(app.ProjectionPanel, 'numeric');
         app.MonitorEditField.Limits = [1 Inf];
         app.MonitorEditField.RoundFractionalValues = 'on';
         app.MonitorEditField.ValueDisplayFormat = '%.0f';
         app.MonitorEditField.ValueChangedFcn = createCallbackFcn(app, @MonitorEditFieldValueChanged, true);
         app.MonitorEditField.Value = 2;
         app.BackgroundcolorEditFieldLabel = uilabel(app.ProjectionPanel);
         app.BackgroundcolorEditFieldLabel.Text = 'Background color';
         app.BackgroundcolorEditField = uieditfield(app.ProjectionPanel, 'text');
         app.BackgroundcolorEditField.ValueChangedFcn = createCallbackFcn(app, @BackgroundcolorEditFieldValueChanged, true);
         app.WidthcmEditFieldLabel = uilabel(app.ProjectionPanel);
         app.WidthcmEditFieldLabel.Text = 'Width (cm)';
         app.WidthcmEditField = uieditfield(app.ProjectionPanel, 'numeric');
         app.WidthcmEditField.Limits = [0 Inf];
         app.WidthcmEditField.ValueChangedFcn = createCallbackFcn(app, @WidthcmEditFieldValueChanged, true);
         app.WidthcmEditField.Value = 100;
         app.PsychtoolboxTab = uitab(app.TabGroup);
         app.PsychtoolboxTab.Title = 'Psychtoolbox';
         app.PingLamp = uilamp(app.PsychtoolboxTab);
         app.PingButton = uibutton(app.PsychtoolboxTab, 'push');
         app.PingButton.ButtonPushedFcn = createCallbackFcn(app, @PingButtonPushed, true);
         app.PingButton.Text = 'Ping';
         app.AddressEditField = uieditfield(app.PsychtoolboxTab, 'text');
         app.AddressEditField.Editable = 'off';
         app.LocationDropDownLabel = uilabel(app.PsychtoolboxTab);
         app.LocationDropDownLabel.Text = 'Location';
         app.LocationDropDown = uidropdown(app.PsychtoolboxTab);
         app.LocationDropDown.Items = {'Local', 'Remote'};
         app.LocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationDropDownValueChanged, true);
         app.LocationDropDown.Value = 'Local';
         app.StopButton = uibutton(app.UIFigure, 'state');
         app.StopButton.Enable = 'off';
         app.StopButton.Text = 'Stop';
         app.PauseButton = uibutton(app.UIFigure, 'state');
         app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
         app.PauseButton.Enable = 'off';
         app.PauseButton.Text = 'Pause';
         app.LLampLabel = uilabel(app.UIFigure);
         app.LLampLabel.Text = 'L';
         app.LLamp = uilamp(app.UIFigure);
         app.RLampLabel = uilabel(app.UIFigure);
         app.RLampLabel.Text = 'R';
         app.RLamp = uilamp(app.UIFigure);
         app.ChairControlPanel = uipanel(app.UIFigure);
         app.DisablechairButton = uibutton(app.ChairControlPanel, 'push');
         app.DisablechairButton.ButtonPushedFcn = createCallbackFcn(app, @DisablechairButtonPushed, true);
         app.DisablechairButton.Text = 'Disable chair';
         app.EnablechairButton = uibutton(app.ChairControlPanel, 'push');
         app.EnablechairButton.ButtonPushedFcn = createCallbackFcn(app, @EnablechairButtonPushed, true);
         app.EnablechairButton.Text = 'Enable chair';
         app.HomeButton = uibutton(app.ChairControlPanel, 'push');
         app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
         app.HomeButton.Enable = 'off';
         app.HomeButton.Text = 'Home';
         app.TestingButton = uibutton(app.ChairControlPanel, 'push');
         app.TestingButton.ButtonPushedFcn = createCallbackFcn(app, @TestingButtonPushed, true);
         app.TestingButton.Enable = 'off';
         app.TestingButton.Text = 'Testing';
         app.Panel = uipanel(app.UIFigure);
         app.PositiondegreesEditFieldLabel = uilabel(app.Panel);
         app.PositiondegreesEditFieldLabel.Text = 'Position: degrees';
         app.DegreesEditField = uieditfield(app.Panel, 'numeric');
         app.DegreesEditField.Editable = 'off';
         app.countsEditFieldLabel = uilabel(app.Panel);
         app.countsEditFieldLabel.Text = 'counts';
         app.CountsEditField = uieditfield(app.Panel, 'numeric');
         app.CountsEditField.ValueDisplayFormat = '%d';
         app.CountsEditField.Editable = 'off';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = VPT

         runningApp = getRunningApp(app);

         % Check for running singleton app
         if isempty(runningApp)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)
         else

            % Focus the running singleton app
            figure(runningApp.UIFigure)

            app = runningApp;
         end

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end